classdef Filter_Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        WindowTypeDropDownLabel        matlab.ui.control.Label
        WindowTypeDropDown             matlab.ui.control.DropDown
        FilterLengthEditFieldLabel     matlab.ui.control.Label
        FilterLengthEditField          matlab.ui.control.NumericEditField
        DesignMethodButtonGroup        matlab.ui.container.ButtonGroup
        WindowedsincButton             matlab.ui.control.RadioButton
        LeastSquaresButton             matlab.ui.control.RadioButton
        WeightedLeastSquaresButton     matlab.ui.control.RadioButton
        NooffrequencypointsEditFieldLabel  matlab.ui.control.Label
        NooffrequencypointsEditField   matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField     matlab.ui.control.NumericEditField
        CutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        CutoffFrequencyEditField       matlab.ui.control.NumericEditField
        ExportFilterButton             matlab.ui.control.Button
        WeightforthepassbandEditFieldLabel  matlab.ui.control.Label
        WeightforthepassbandEditField  matlab.ui.control.NumericEditField
        WeightforthetransitionbandEditFieldLabel  matlab.ui.control.Label
        WeightforthetransitionbandEditField  matlab.ui.control.NumericEditField
        WeightforthestopbandEditFieldLabel  matlab.ui.control.Label
        WeightforthestopbandEditField  matlab.ui.control.NumericEditField
        PassbandcutofffrequencyEditFieldLabel  matlab.ui.control.Label
        PassbandcutofffrequencyEditField  matlab.ui.control.NumericEditField
        StopbandstartfrequencyEditFieldLabel  matlab.ui.control.Label
        StopbandstartfrequencyEditField  matlab.ui.control.NumericEditField
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        LinearTab                      matlab.ui.container.Tab
        Frequency_Domain_axes_Linear   matlab.ui.control.UIAxes
        LogarithmicTab                 matlab.ui.container.Tab
        Frequency_Domain_axes_Log      matlab.ui.control.UIAxes
        Time_Domain_axes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        L   % Length of the window
        win_type % Type of the window
        fc  % Cutoff frequency
        fs  % Sampling frequency
        k   % Number of frequency points (higher for resolution)
        W_p % Weight for the passband
        W_t % Weight for the transition band
        W_s % Weight for the stopband
        f_pass  % Passband cutoff frequency
        f_stop  % Stopband start frequency
        coeff_export
    end
    
    methods (Access = private)
        
        function results = updateGraphs(app)
            if(get(app.WindowedsincButton,'Value'))
                [n,coeff,f,H] = windowed_sinc(app.L,app.win_type,app.fc,app.fs);
            end 
            if (get(app.LeastSquaresButton,'Value'))
               [n, coeff, f, H] = ls_fir_filter(app.L, app.k, app.fc,app.fs);
            end
            if (get(app.WeightedLeastSquaresButton,'Value'))
                [n, coeff, f, H] = wls_fir_filter(app.W_p, app.W_t, app.W_s, app.L, app.k, app.f_pass, app.f_stop,app.fs);
            end
            app.Time_Domain_axes.XLim = [0 app.L];
            stem(app.Time_Domain_axes,n,coeff)
            semilogy(app.Frequency_Domain_axes_Log,f,abs(H));
            plot(app.Frequency_Domain_axes_Linear,f,abs(H))
            app.coeff_export = coeff;
            results = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.L = 51;
            app.win_type = 'Rectangular';
            app.fc = 10000;
            app.fs = 44100;
            app.k = 500;
            app.W_p = 100;
            app.W_t = 1;
            app.W_s = 1;
            app.f_pass = 5000;
            app.f_stop = 5500;
            app.updateGraphs();
        end

        % Value changed function: WindowTypeDropDown
        function ChangeWindowType(app, event)
            app.win_type = app.WindowTypeDropDown.Value;
            app.updateGraphs();
        end

        % Value changed function: FilterLengthEditField
        function ChangeLength(app, event)
            % This is to ensure that the filter length is odd
            if(mod(app.FilterLengthEditField.Value,2) == 0)
                app.FilterLengthEditField.Value = app.FilterLengthEditField.Value + 1;
            end
            app.L = app.FilterLengthEditField.Value;
            app.updateGraphs();
        end

        % Selection changed function: DesignMethodButtonGroup
        function ChangeMethod(app, event)
            app.updateGraphs();
            if(get(app.LeastSquaresButton,'Value'))
                app.NooffrequencypointsEditField.Enable = true;
                app.NooffrequencypointsEditFieldLabel.Enable = true;
                app.CutoffFrequencyEditFieldLabel.Enable = true;
                app.CutoffFrequencyEditField.Enable = true;
                app.WindowTypeDropDown.Enable = false;
                app.WindowTypeDropDownLabel.Enable = false;
            end
            if(get(app.WeightedLeastSquaresButton,'Value'))
                app.NooffrequencypointsEditField.Enable = true;
                app.NooffrequencypointsEditFieldLabel.Enable = true;
                
                app.WeightforthepassbandEditFieldLabel.Enable = true;
                app.WeightforthepassbandEditField.Enable = true;
                
                app.WeightforthetransitionbandEditFieldLabel.Enable = true;
                app.WeightforthetransitionbandEditField.Enable = true;
                
                app.WeightforthestopbandEditFieldLabel.Enable = true;
                app.WeightforthestopbandEditField.Enable = true;
                
                app.PassbandcutofffrequencyEditFieldLabel.Enable = true;
                app.PassbandcutofffrequencyEditField.Enable = true;
                
                app.StopbandstartfrequencyEditFieldLabel.Enable = true;
                app.StopbandstartfrequencyEditField.Enable = true;
                
                app.WindowTypeDropDown.Enable = false;
                app.WindowTypeDropDownLabel.Enable = false;
                
                app.CutoffFrequencyEditFieldLabel.Enable = false;
                app.CutoffFrequencyEditField.Enable = false;
            else
                app.WeightforthepassbandEditFieldLabel.Enable = false;
                app.WeightforthepassbandEditField.Enable = false;
                
                app.WeightforthetransitionbandEditFieldLabel.Enable = false;
                app.WeightforthetransitionbandEditField.Enable = false;
                
                app.WeightforthestopbandEditFieldLabel.Enable = false;
                app.WeightforthestopbandEditField.Enable = false;
                
                app.PassbandcutofffrequencyEditFieldLabel.Enable = false;
                app.PassbandcutofffrequencyEditField.Enable = false;
                
                app.StopbandstartfrequencyEditFieldLabel.Enable = false;
                app.StopbandstartfrequencyEditField.Enable = false;
            end
            if (get(app.WindowedsincButton,'Value'))
                app.NooffrequencypointsEditField.Enable = false;
                app.NooffrequencypointsEditFieldLabel.Enable = false;
                app.WindowTypeDropDown.Enable = true;
                app.WindowTypeDropDownLabel.Enable = true;
                app.CutoffFrequencyEditFieldLabel.Enable = true;
                app.CutoffFrequencyEditField.Enable = true;
            end
        end

        % Value changed function: CutoffFrequencyEditField
        function ChangeFc(app, event)
            app.fc = app.CutoffFrequencyEditField.Value;
            if (app.fc*2 >= app.fs)
                app.fc = app.fs/2 - 1;
                app.CutoffFrequencyEditField.Value = app.fs/2 - 1;
            end
            app.updateGraphs();
        end

        % Value changed function: NooffrequencypointsEditField
        function ChangeK(app, event)
            app.k = app.NooffrequencypointsEditField.Value;
            app.updateGraphs();
        end

        % Button pushed function: ExportFilterButton
        function ExportFilterButtonPushed(app, event)
            filter_specs.filter_time_domain = app.coeff_export;
            filter_specs.filter_L = app.L;
            filter_specs.filter_win_type = app.win_type;
            filter_specs.filter_fs = app.fs;
            filter_specs.filter_fc = app.fc;
            filter_specs.filter_k = app.k;
            
            save("filter.mat", "filter_specs", "-mat");
        end

        % Value changed function: WeightforthepassbandEditField
        function ChangeWeightPassband(app, event)
            app.W_p = app.WeightforthepassbandEditField.Value;
            app.updateGraphs();
        end

        % Value changed function: 
        % WeightforthetransitionbandEditField
        function ChangeWeightTransitionband(app, event)
            app.W_t = app.WeightforthetransitionbandEditField.Value;
            app.updateGraphs();
        end

        % Value changed function: WeightforthestopbandEditField
        function ChangeWeightStopband(app, event)
            app.W_s = app.WeightforthestopbandEditField.Value;
            app.updateGraphs();
        end

        % Value changed function: PassbandcutofffrequencyEditField
        function ChangePassbandFc(app, event)
            if (app.PassbandcutofffrequencyEditField.Value >= app.fs/2)
                app.PassbandcutofffrequencyEditField.Value = app.fs/2 - 2;
            end
            app.f_pass = app.PassbandcutofffrequencyEditField.Value;
            app.updateGraphs();
        end

        % Value changed function: StopbandstartfrequencyEditField
        function ChangeStopbandFc(app, event)
            if (app.StopbandstartfrequencyEditField.Value >= app.fs/2)
                app.StopbandstartfrequencyEditField.Value = app.fs/2 - 1;
            end
            if (app.StopbandstartfrequencyEditField.Value <= app.f_pass)
                app.StopbandstartfrequencyEditField.Value = app.f_pass + 1;
            end
            app.f_stop = app.StopbandstartfrequencyEditField.Value;
            app.updateGraphs();
        end

        % Value changed function: SamplingFrequencyEditField
        function ChangeFs(app, event)
            if(app.SamplingFrequencyEditField.Value <= 2*app.fc)
                app.SamplingFrequencyEditField.Value = 2*app.fc + 1;
            end
            app.fs = app.SamplingFrequencyEditField.Value;
            app.updateGraphs();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {502, 502};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WindowTypeDropDownLabel = uilabel(app.LeftPanel);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowTypeDropDown = uidropdown(app.LeftPanel);
            app.WindowTypeDropDown.Items = {'Rectangular', 'Blackman', 'Chebyshev', 'Kaiser'};
            app.WindowTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeWindowType, true);
            app.WindowTypeDropDown.Value = 'Rectangular';
            app.FilterLengthEditFieldLabel = uilabel(app.LeftPanel);
            app.FilterLengthEditFieldLabel.Text = 'Filter Length';
            app.FilterLengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FilterLengthEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeLength, true);
            app.FilterLengthEditField.Value = 51;
            app.DesignMethodButtonGroup = uibuttongroup(app.LeftPanel);
            app.DesignMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChangeMethod, true);
            app.DesignMethodButtonGroup.Title = 'Design Method';
            app.WindowedsincButton = uiradiobutton(app.DesignMethodButtonGroup);
            app.WindowedsincButton.Text = 'Windowed sinc';
            app.WindowedsincButton.Value = true;
            app.LeastSquaresButton = uiradiobutton(app.DesignMethodButtonGroup);
            app.LeastSquaresButton.Text = 'Least Squares';
            app.WeightedLeastSquaresButton = uiradiobutton(app.DesignMethodButtonGroup);
            app.WeightedLeastSquaresButton.Text = 'Weighted Least Squares';
            app.NooffrequencypointsEditFieldLabel = uilabel(app.LeftPanel);
            app.NooffrequencypointsEditFieldLabel.Enable = 'off';
            app.NooffrequencypointsEditFieldLabel.Text = 'No. of frequency points';
            app.NooffrequencypointsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NooffrequencypointsEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeK, true);
            app.NooffrequencypointsEditField.Enable = 'off';
            app.NooffrequencypointsEditField.Value = 500;
            app.SamplingFrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeFs, true);
            app.SamplingFrequencyEditField.Value = 44100;
            app.CutoffFrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.CutoffFrequencyEditFieldLabel.Text = 'Cut-off Frequency';
            app.CutoffFrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CutoffFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeFc, true);
            app.CutoffFrequencyEditField.Value = 10000;
            app.ExportFilterButton = uibutton(app.LeftPanel, 'push');
            app.ExportFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFilterButtonPushed, true);
            app.ExportFilterButton.Text = 'Export Filter';
            app.WeightforthepassbandEditFieldLabel = uilabel(app.LeftPanel);
            app.WeightforthepassbandEditFieldLabel.Enable = 'off';
            app.WeightforthepassbandEditFieldLabel.Text = 'Weight for the passband';
            app.WeightforthepassbandEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WeightforthepassbandEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeWeightPassband, true);
            app.WeightforthepassbandEditField.Enable = 'off';
            app.WeightforthepassbandEditField.Value = 100;
            app.WeightforthetransitionbandEditFieldLabel = uilabel(app.LeftPanel);
            app.WeightforthetransitionbandEditFieldLabel.Enable = 'off';
            app.WeightforthetransitionbandEditFieldLabel.Text = 'Weight for the transition band';
            app.WeightforthetransitionbandEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WeightforthetransitionbandEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeWeightTransitionband, true);
            app.WeightforthetransitionbandEditField.Enable = 'off';
            app.WeightforthetransitionbandEditField.Value = 1;
            app.WeightforthestopbandEditFieldLabel = uilabel(app.LeftPanel);
            app.WeightforthestopbandEditFieldLabel.Enable = 'off';
            app.WeightforthestopbandEditFieldLabel.Text = 'Weight for the stopband';
            app.WeightforthestopbandEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WeightforthestopbandEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeWeightStopband, true);
            app.WeightforthestopbandEditField.Enable = 'off';
            app.WeightforthestopbandEditField.Value = 1;
            app.PassbandcutofffrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.PassbandcutofffrequencyEditFieldLabel.Enable = 'off';
            app.PassbandcutofffrequencyEditFieldLabel.Text = 'Passband cutoff frequency';
            app.PassbandcutofffrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PassbandcutofffrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @ChangePassbandFc, true);
            app.PassbandcutofffrequencyEditField.Enable = 'off';
            app.PassbandcutofffrequencyEditField.Value = 5000;
            app.StopbandstartfrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.StopbandstartfrequencyEditFieldLabel.Enable = 'off';
            app.StopbandstartfrequencyEditFieldLabel.Text = 'Stopband start frequency';
            app.StopbandstartfrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StopbandstartfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeStopbandFc, true);
            app.StopbandstartfrequencyEditField.Enable = 'off';
            app.StopbandstartfrequencyEditField.Value = 5500;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.LinearTab = uitab(app.TabGroup);
            app.LinearTab.Title = 'Linear';
            app.Frequency_Domain_axes_Linear = uiaxes(app.LinearTab);
            title(app.Frequency_Domain_axes_Linear, 'Fitler Respose (frequency domain)')
            xlabel(app.Frequency_Domain_axes_Linear, 'Frequency (normalised)')
            ylabel(app.Frequency_Domain_axes_Linear, 'Amplitude')
            zlabel(app.Frequency_Domain_axes_Linear, 'Z')
            app.LogarithmicTab = uitab(app.TabGroup);
            app.LogarithmicTab.Title = 'Logarithmic ';
            app.Frequency_Domain_axes_Log = uiaxes(app.LogarithmicTab);
            title(app.Frequency_Domain_axes_Log, 'Fitler Respose (frequency domain)')
            xlabel(app.Frequency_Domain_axes_Log, 'Frequency (normalised)')
            ylabel(app.Frequency_Domain_axes_Log, 'Amplitude')
            zlabel(app.Frequency_Domain_axes_Log, 'Z')
            app.Time_Domain_axes = uiaxes(app.RightPanel);
            title(app.Time_Domain_axes, 'Fitler Respose (time domain)')
            xlabel(app.Time_Domain_axes, 'Time (n)')
            ylabel(app.Time_Domain_axes, 'Amplitude')
            zlabel(app.Time_Domain_axes, 'Z')
            app.Time_Domain_axes.XLim = [0 1];
            app.Time_Domain_axes.YLim = [-0.5 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filter_Design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end